classdef fdn < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LoadDataMenu                   matlab.ui.container.Menu
        IndexfileMenu                  matlab.ui.container.Menu
        PreloadedDataMenu              matlab.ui.container.Menu
        PublicDataMenu                 matlab.ui.container.Menu
        AnalyzeMenu                    matlab.ui.container.Menu
        ChromatinpartitioningMenu      matlab.ui.container.Menu
        FiedlervectorMenu              matlab.ui.container.Menu
        Principalcomponent1Menu        matlab.ui.container.Menu
        DifferentialexpressionMenu     matlab.ui.container.Menu
        DNfeatureanalysisMenu          matlab.ui.container.Menu
        LarntzPerlmanMenu              matlab.ui.container.Menu
        ABswitchingMenu                matlab.ui.container.Menu
        EffectiveresistanceMenu        matlab.ui.container.Menu
        VisualizeMenu                  matlab.ui.container.Menu
        GenomewideMenu                 matlab.ui.container.Menu
        HiCRNAseqMenu                  matlab.ui.container.Menu
        DNfeatureanalysisMenu_2        matlab.ui.container.Menu
        DataExportMenu                 matlab.ui.container.Menu
        dataInfoMenu                   matlab.ui.container.Menu
        HMenu                          matlab.ui.container.Menu
        kbMenu                         matlab.ui.container.Menu
        MbMenu                         matlab.ui.container.Menu
        RMenu                          matlab.ui.container.Menu
        TPMMenu                        matlab.ui.container.Menu
        countsMenu                     matlab.ui.container.Menu
        RPKMMenu                       matlab.ui.container.Menu
        ExamplesMenu                   matlab.ui.container.Menu
        LarntzPerlmanMenu_2            matlab.ui.container.Menu
        chrlevelcomparisonMenu         matlab.ui.container.Menu
        AddchromosometofigureMenu      matlab.ui.container.Menu
        KarytypegenesetexpressionMenu  matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        DocumentationMenu              matlab.ui.container.Menu
        UIAxes                         matlab.ui.control.UIAxes
        TextArea_2                     matlab.ui.control.TextArea
        DNvestigatorLabel              matlab.ui.control.Label
    end

    
    properties (Access = public)
        dataInfo % data information structure
        H % Hi-C data structure
        R % RNA-seq data structure
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% general start-up
            %clear
            %close all
            restoredefaultpath
            addpath(genpath('.'))
            rmpath(genpath('./functions/depreciated'))
            
            %% get computer info
            if isunix
                app.dataInfo.delim = '/';
            else
                app.dataInfo.delim = '\';
            end
            
            %% load often used variable
            app.dataInfo.hicCMap = [ones(64,1),[1:-1/63:0]',[1:-1/63:0]'];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% plot on axes for start up
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%
            createStartUpFig
%             tempH = load('HiC1M_FIB_raw.mat', 'H', 'chr');
%             tempR = load('RNAseq1M.mat', 'RNAseq_av');
%             
%             chrSelect = 14;
%             tempChr = tempH.chr;
%             a = log(tempH.H(tempChr(chrSelect):tempChr(chrSelect+1)-1,...
%                 tempChr(chrSelect):tempChr(chrSelect+1)-1,1:8));
%             c = log(tempR.RNAseq_av(tempChr(chrSelect):tempChr(chrSelect+1)-1,:)+1)';
%             
%             % trim Chr
%             a(~isfinite(a)) = 0;
%             a(isnan(a)) = 0;
%             remLocs = any(squeeze(sum(a,1) == 0),2);
%             
%             a(remLocs,:,:) = [];
%             a(:,remLocs,:) = [];
%             c(:,remLocs) = [];
%             
%             % begin plot
%             outline = 0;
%             scale = .01;%1/size(a,3);
%             
%             b = a;
%             
%             for i = 1:size(a,3)
%                 aa(:,:,i) = [a(:,1,i),a(:,:,i)];
%                 aaa(:,:,i) = [aa(1,:,i);aa(:,:,i)];
%                 aaa(:,:,i) = rot90(aaa(:,:,i)',2);
%             end
%             a = aaa;
%             
%             a = permute(a,[1,3,2]);
%             zslice = [];
%             yslice = [];
%             xslice = [1:size(a,2)];
%             
%             [x,y,z] = meshgrid(1:1:size(a,2),1:1:size(a,1),-size(a,3)+1:1:0);
%             
%             h = slice(app.UIAxes,x,y,z,a,xslice,yslice,zslice);
%             set(h,'EdgeColor','none')
%             xlabel(app.UIAxes,'Time');ylabel(app.UIAxes,'y');zlabel(app.UIAxes,'z');
%             ylim(app.UIAxes,[-1 size(b,1)+1])
%             daspect(app.UIAxes,[scale 1 1])
%             view(app.UIAxes,-61,22)
%             axis(app.UIAxes,'off')
%             hold(app.UIAxes,'on')
%             app.UIAxes.XLabel.Visible = 'on';
%             title(app.UIAxes,sprintf('Proliferating Fibroblast 4DN data, Chr%i',chrSelect))
%             
%             if outline == 1
%                 for i = 1:size(b,3)
%                     plot3(app.UIAxes,[i,i],[1,1],[0,-size(b,1)],'k-')
%                     plot3(app.UIAxes,[i,i],[1,size(b,1)+1],[-size(b,1),-size(b,1)],'k-')
%                     plot3(app.UIAxes,[i,i],[size(b,1)+1,size(b,1)+1],[-size(b,1),0],'k-')
%                     plot3(app.UIAxes,[i,i],[size(b,1)+1,1],[0,0],'k-')
%                 end
%             end
%             colormap(app.UIAxes,'hot')
%             
%             bar3_sr(app.UIAxes,flipud(c'))
            
        end

        % Menu selected function: IndexfileMenu
        function IndexfileMenuSelected(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% Process Input Data Information
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            answer = [];
            while ~strcmp(answer,'Yes')
                answer = questdlg('Do you have an Index File for your data set?', ...
                    'input Index File',...
                    'Yes', 'No', 'What is this?',...
                    'Yes');
                
                % Handle response
                switch answer
                    case 'Yes'
                        [file,path] = uigetfile('*','Select Index File:',pwd);
                        app.dataInfo.indexFile = [path,file];
                        
                    case 'No'
                        fprintf('please create one, manual input not available at this time.\n')
                        
                    case 'What is this?'
                        fprintf(['An index file is a tab or comma separated file that includes ',...
                            'information on the samples being process.\n',...
                            'files include the following fields: path, dataType, sample, timePoint.\n\n',...
                            'path: full path for the data file on your computer.\n',...
                            'dataType: type of data (hic or rnaseq).\n',...
                            'sample: sample name (string).\n',...
                            'timePoint: time point of data (integer).\n\n',...
                            'An example Index File is located in ./sampleData/sampleDataIndex.xlsx\n'])
                end
            end
            
            app.dataInfo.sampleInfo = readtable(app.dataInfo.indexFile);
            
            % extract sample names from sample filename
            tempName = cell(height(app.dataInfo.sampleInfo),1);
            for iSample = 1:height(app.dataInfo.sampleInfo)
                temp1 = strsplit(app.dataInfo.sampleInfo.path{iSample},app.dataInfo.delim);
                temp2 = strsplit(temp1{end},{'.'});
                tempName{iSample} = temp2{1};
            end
            app.dataInfo.sampleInfo.name = tempName;
            
            %% read .hic header files
            fprintf('reading .hic header information...\n')
            
            % get .hic samples locations
            hicSampleLocs = find(ismember(app.dataInfo.sampleInfo.dataType,'hic'));
            
            % read all .hic headers and get reference genome information
            hicHeader = cell(length(hicSampleLocs),1);
            refGenome = cell(length(hicSampleLocs),1);
            for iHicSample = 1:length(hicSampleLocs)
                hicHeader{iHicSample} = readHicHeader(app.dataInfo.sampleInfo.path{hicSampleLocs(iHicSample)});
                refGenome{iHicSample} = hicHeader{iHicSample}.refGenome;
            end
            
            % determine if all samples were mapped to same reference genome
            if length(unique(refGenome)) == 1
                app.dataInfo.hicHeader = hicHeader{iHicSample};
                
                % remove "ALL" and "M" chr
                app.dataInfo.hicHeader.Chromosomes(ismember(upper(app.dataInfo.hicHeader.Chromosomes.chr),{'ALL','M'}),:) = [];
                
            else
                error('.hic files have different reference genomes')
            end
            
            %% Get Project Name
            if exist('projectName','var')
                app.dataInfo.projName = projectName;
            else
                temp = inputdlg('Input Project Name:','Project Name');
                app.dataInfo.projName = temp{1};
            end
            
            %% Create replicate identifier
            [uvals, ~, uidx] = unique(strcat(app.dataInfo.sampleInfo.sample,app.dataInfo.sampleInfo.dataType));
            replicate = ones(height(app.dataInfo.sampleInfo),1);    % used to mostly to copy the class and size
            for K = 1 : length(uvals)
                mask = uidx == K;
                replicate(mask) = [1:sum(mask)]';
            end
            app.dataInfo.sampleInfo.replicate = replicate;
            
            % Create uniqname from info
            app.dataInfo.sampleInfo.uniqueName = strcat(app.dataInfo.sampleInfo.dataType,...
                {'_s'},app.dataInfo.sampleInfo.sample,...
                {'_t'},cellstr(num2str(app.dataInfo.sampleInfo.timePoint)),...
                {'_r'},cellstr(num2str(app.dataInfo.sampleInfo.replicate)));
            
            % sort sampleInfo
            app.dataInfo.sampleInfo = sortrows(app.dataInfo.sampleInfo,{'dataType','sample','timePoint'},{'ascend','ascend','ascend'});
            
            % Create sampleSlice (to be filled later as data is loaded)
            app.dataInfo.sampleInfo.index = [1:sum(ismember(app.dataInfo.sampleInfo.dataType,'hic')),...
                1:sum(ismember(app.dataInfo.sampleInfo.dataType,'rnaseq'))]';
            
            %% Create analysis output directory
            if exist('outputPath','var')
                app.dataInfo.path.output = outputPath;
            else
                app.dataInfo.path.output = uigetdir(pwd,'Select Output folder');
            end
            
            % make subdirectories if new folder
            mkdir([app.dataInfo.path.output,app.dataInfo.delim,'figures'])
            mkdir([app.dataInfo.path.output,app.dataInfo.delim,'tables'])
            mkdir([app.dataInfo.path.output,app.dataInfo.delim,'data'])
            mkdir([app.dataInfo.path.output,app.dataInfo.delim,'data',app.dataInfo.delim,'gsaa'])
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%
            %% load Hi-C
            %%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %% get chr information from hic header
            chrInfo = app.dataInfo.hicHeader.Chromosomes;
            numChr = height(chrInfo);
            
            % get Hi-C sample Locations
            sampleFn = app.dataInfo.sampleInfo.path(ismember(app.dataInfo.sampleInfo.dataType,'hic'));
            
            % Create the waitbar and determine intialization properties
            waitBar = waitbar(0,'loading 100kb Hi-C...','Name','Loading Data');
            set(findall(waitBar),'Units', 'normalized');    % Change the Units Property of the figure and all the children
            set(waitBar,'Position', [0.25 0.4 0.5 0.08]);   % Change the size of the figure
            totalWait = numChr*length(sampleFn);
            currentWait = 1;
            avTime = 0;
            
            %% clear previous axis image
            daspect(app.UIAxes,[1 1 1])
            view(app.UIAxes,2)
            cla(app.UIAxes)
            hold(app.UIAxes, 'off')
            
            %% load Hi-C data - 100kb
            % this loads intra-chr Hi-C
            %%% PARAMETERS vvv
            hicParam.binType = 'BP';
            hicParam.binSize = 1E5;
            hicParam.norm1d = 'KR';
            hicParam.norm3d = 'oe';
            hicParam.intraFlag = 1;
            %%% PARAMETERS ^^^
            
            chrBinSizes = ceil(chrInfo.chrLength/hicParam.binSize);
            app.H.s100kb.oe = cell(numChr,1);
            app.H.s100kb.kr = cell(numChr,1);
            
            for iChr = 1:numChr
                app.H.s100kb.oe{iChr} = zeros(chrBinSizes(iChr),chrBinSizes(iChr),length(sampleFn));
                app.H.s100kb.kr{iChr} = zeros(chrBinSizes(iChr),chrBinSizes(iChr),length(sampleFn));
                for iSample = 1:length(sampleFn)
                    % for estimation of time
                    if iChr==1 && iSample==1;tic;end
                    
                    % update load bar
                    waitbar(currentWait/totalWait,waitBar,...
                        sprintf('Loading 100kb Hi-C. Sample: (%d/%d), chr:%s, estimated time: %i secs...',...
                        iSample,length(sampleFn),chrInfo.chr{iChr},round(avTime*(totalWait-currentWait))));
                    
                    % extract o/e normalized
                    temp = hic2mat(hicParam.norm3d,hicParam.norm1d,sampleFn{iSample},...
                        chrInfo.chr{iChr},chrInfo.chr{iChr},hicParam.binType,hicParam.binSize,hicParam.intraFlag);
                    temp(isnan(temp)) = 0;
                    temp = max(cat(3,temp,temp'),[],3);
                    app.H.s100kb.oe{iChr}(1:length(temp),1:length(temp),iSample) = temp;
                    
                    % plot current loaded structure
                    temp(temp>prctile(temp(:),99)) = prctile(temp(:),99);
                    imagesc(app.UIAxes,temp)
                    axis(app.UIAxes,'tight')
                    colormap(app.UIAxes,app.dataInfo.hicCMap);
                    xlabel(app.UIAxes,chrInfo.chr{iChr}), ylabel(app.UIAxes,chrInfo.chr{iChr})
                    title(app.UIAxes,sprintf('Sample: %s, Time Point: %i, Chr: %i',...
                        app.dataInfo.sampleInfo.sample{iSample},...
                        app.dataInfo.sampleInfo.timePoint(iSample),iChr))
                    %colorbarScaled(.3)
                    
                    % extract observed as well
                    temp = hic2mat('observed',hicParam.norm1d,sampleFn{iSample},...
                        chrInfo.chr{iChr},chrInfo.chr{iChr},hicParam.binType,hicParam.binSize,hicParam.intraFlag);
                    temp(isnan(temp)) = 0;
                    temp = max(cat(3,temp,temp'),[],3);
                    app.H.s100kb.kr{iChr}(1:length(temp),1:length(temp),iSample) = temp;
                    
                    % update load bar variable
                    currentWait = currentWait+1;
                    if iChr==1 && iSample==1;avTime = toc;end % for estimation of time to load
                end
            end
            close(waitBar)
            
            %% load Hi-C data - 1Mb
            % this loads both intra- and inter-chr Hi-C
            %%% PARAMETERS vvv
            hicParam.binSize = 1E6;
            %%% PARAMETERS ^^^
            
            % Create the waitbar and determine intialization properties
            waitBar = waitbar(0,'loading 1Mb Hi-C...','Name','Loading Data');
            set(findall(waitBar),'Units', 'normalized');    % Change the Units Property of the figure and all the children
            set(waitBar,'Position', [0.25 0.4 0.5 0.08]);   % Change the size of the figure
            totalWait = (nchoosek(numChr,2)+numChr)*length(sampleFn);
            currentWait = 1;
            avTime = 0;
            
            % get Hi-C data
            app.H.s1mb.chrStart = [1;cumsum(ceil(chrInfo.chrLength/hicParam.binSize))+1];
            app.H.s1mb.oe = zeros(app.H.s1mb.chrStart(end)-1,app.H.s1mb.chrStart(end)-1,length(sampleFn));
            app.H.s1mb.kr = zeros(app.H.s1mb.chrStart(end)-1,app.H.s1mb.chrStart(end)-1,length(sampleFn));
            for iSample = 1:length(sampleFn)
                for iChr1 = 1:numChr
                    for iChr2 = iChr1:numChr
                        % for estimation of time
                        if iChr1==1 && iChr2==1 && iSample==1;tic;end
                        
                        % update load bar
                        waitbar(currentWait/totalWait,waitBar,...
                            sprintf('Loading 100kb Hi-C. Sample: (%d/%d), chr1:%s, chr2:%s, estimated time: %i secs...',...
                            iSample,length(sampleFn),chrInfo.chr{iChr1},chrInfo.chr{iChr2},...
                            round(avTime*(totalWait-currentWait))));
                        
                        % o/e
                        temp = hic2mat(hicParam.norm3d,hicParam.norm1d,sampleFn{iSample},...
                            chrInfo.chr{iChr1},chrInfo.chr{iChr2},hicParam.binType,hicParam.binSize);
                        
                        %if chrSizesSorted(iChr2)>chrSizesSorted(iChr1); temp=temp';end
                        app.H.s1mb.oe(app.H.s1mb.chrStart(iChr1):app.H.s1mb.chrStart(iChr1)+size(temp,1)-1,...
                            app.H.s1mb.chrStart(iChr2):app.H.s1mb.chrStart(iChr2)+size(temp,2)-1,iSample) = temp;
                        
                        % plot current loaded structure
                        temp(temp>prctile(temp(:),99)) = prctile(temp(:),99);
                        imagesc(app.UIAxes,temp)
                        axis(app.UIAxes,'tight')
                        colormap(app.UIAxes,app.dataInfo.hicCMap);
                        xlabel(app.UIAxes,chrInfo.chr{iChr1}), ylabel(app.UIAxes,chrInfo.chr{iChr2})
                        title(app.UIAxes,sprintf('Sample: %s, Time Point: %i, Chr1: %i, Chr2: %i',...
                            app.dataInfo.sampleInfo.sample{iSample},...
                            app.dataInfo.sampleInfo.timePoint(iSample),iChr1,iChr2))
                            
                        % observed
                        temp = hic2mat('observed',hicParam.norm1d,sampleFn{iSample},...
                            chrInfo.chr{iChr1},chrInfo.chr{iChr2},hicParam.binType,hicParam.binSize);
                        
                        %if chrSizesSorted(iChr2)>chrSizesSorted(iChr1); temp=temp';end
                        app.H.s1mb.kr(app.H.s1mb.chrStart(iChr1):app.H.s1mb.chrStart(iChr1)+size(temp,1)-1,...
                            app.H.s1mb.chrStart(iChr2):app.H.s1mb.chrStart(iChr2)+size(temp,2)-1,iSample) = temp;
                            
                        % update load bar variable
                        currentWait = currentWait+1;
                        
                        % for estimation of time to load
                        if iChr1==1 && iChr2==1 && iSample==1;avTime = toc;end
                    end
                end
                app.H.s1mb.oe(:,:,iSample) = max(cat(3,app.H.s1mb.oe(:,:,iSample),app.H.s1mb.oe(:,:,iSample)'),[],3);
                app.H.s1mb.kr(:,:,iSample) = max(cat(3,app.H.s1mb.kr(:,:,iSample),app.H.s1mb.kr(:,:,iSample)'),[],3);
            end
            close(waitBar)
            
            %% trim data (remove unmappable regions) - 100kb and 1Mb
            %%% PARAMETERS vvv
            hicParam.numDiag = 2;
            hicParam.numSparse = .1;
            %%% PARAMETERS ^^^
            
            % trim 100kb all together
            app.H.s100kb.oeTrim = cell(numChr,1);
            app.H.s100kb.krTrim = cell(numChr,1);
            app.H.s100kb.genesTrim = cell(numChr,1);
            app.H.s100kb.oeTrimBadLocs = cell(numChr,1);
            for iChr = 1:numChr
                fprintf('Trimming Hi-C Chr:%d...\n',iChr)
                [app.H.s100kb.oeTrim{iChr},app.H.s100kb.oeTrimBadLocs{iChr}] = hicTrim(app.H.s100kb.oe{iChr},...
                    hicParam.numDiag,hicParam.numSparse);
                app.H.s100kb.krTrim{iChr} = app.H.s100kb.kr{iChr}(~app.H.s100kb.oeTrimBadLocs{iChr},~app.H.s100kb.oeTrimBadLocs{iChr},:);
            end
            
            % trim 1Mb all together
            [app.H.s1mb.oeTrim,app.H.s1mb.oeTrimBadLocs] = hicTrim(app.H.s1mb.oe,...
                hicParam.numDiag,hicParam.numSparse);
            app.H.s1mb.krTrim = app.H.s1mb.kr(~app.H.s1mb.oeTrimBadLocs,~app.H.s1mb.oeTrimBadLocs,:);
            
            % update chr Start site with trimmed Locs
            app.H.s1mb.chrStartTrim = app.H.s1mb.chrStart;
            for iChr = 1:numChr
                app.H.s1mb.chrStartTrim(iChr) = app.H.s1mb.chrStart(iChr)-...
                    sum(app.H.s1mb.oeTrimBadLocs(1:app.H.s1mb.chrStart(iChr)-1));
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%
            %% load RNA-seq
            %%%%%%%%%%%%%%%%%%%%%%%%%%
            % get Hi-C sample Locations
            repGrp = findgroups(app.dataInfo.sampleInfo.sample(strcmp(app.dataInfo.sampleInfo.dataType,'rnaseq')));
            
            % RSEM to mat
            app.R = rsem2mat(app.dataInfo.sampleInfo.path(ismember(app.dataInfo.sampleInfo.dataType,'rnaseq')),...
                app.dataInfo.sampleInfo.name(ismember(app.dataInfo.sampleInfo.dataType,'rnaseq')),...
                app.dataInfo.hicHeader.refGenome);
            
            Rfields = fields(app.R);
            
            for i = 1:length(Rfields)
                app.R.(Rfields{i})(~cellfun(@isnumeric,app.R.(Rfields{i}).chr),:) = [];
                app.R.(Rfields{i}).chr = cell2mat(app.R.(Rfields{i}).chr);
            end
            
            %% bined RNA-seq
            %100kb bins
            chrBinSizes = ceil(chrInfo.chrLength/1E5);
            [app.R.s100kb.tpm,app.R.s100kb.gene] = rna2bin(app.R.TPM{:,7:end},app.R.TPM.geneName,...
                [app.R.TPM.chr app.R.TPM.geneStart app.R.TPM.geneEnd],1E5,chrBinSizes);
            
            % get mean for bins
            app.R.s100kb.tpmMean = cell(numChr,1);
            for iChr = 1:numChr
                app.R.s100kb.tpmMean{iChr} = zeros(size(app.R.s100kb.tpm{iChr},1),max(repGrp));
                for iRep = 1:max(repGrp)
                    app.R.s100kb.tpmMean{iChr}(:,iRep) = mean(app.R.s100kb.tpm{iChr}(:,repGrp==iRep),2);
                end
            end
            
            %1mb bins
            chrBinSizes = ceil(chrInfo.chrLength/1E6);
            [app.R.s1mb.tpm,app.R.s1mb.gene] = rna2bin(app.R.TPM{:,7:end},app.R.TPM.geneName,...
                [app.R.TPM.chr app.R.TPM.geneStart app.R.TPM.geneEnd],1E6,chrBinSizes);
            
            app.R.s1mb.tpm = vertcat(app.R.s1mb.tpm{:});
            app.R.s1mb.gene = vertcat(app.R.s1mb.gene{:});
            
            % get mean for bins
            app.R.s1mb.tpmMean = zeros(size(app.R.s1mb.tpm,1),max(repGrp));
            for iRep = 1:max(repGrp)
                app.R.s1mb.tpmMean(:,iRep) = mean(app.R.s1mb.tpm(:,repGrp==iRep),2);
            end
            
            %trim based on Hi-C
            for iChr = 1:numChr
                app.R.s100kb.tpmTrim{iChr,1} = app.R.s100kb.tpm{iChr}(~app.H.s100kb.oeTrimBadLocs{iChr},:);
                app.R.s100kb.tpmMeanTrim{iChr,1} = app.R.s100kb.tpmMean{iChr}(~app.H.s100kb.oeTrimBadLocs{iChr},:);
                app.R.s100kb.geneTrim{iChr,1} = app.R.s100kb.gene{iChr}(~app.H.s100kb.oeTrimBadLocs{iChr},:);
            end
            
            app.R.s1mb.tpmTrim = app.R.s1mb.tpm(~app.H.s1mb.oeTrimBadLocs,:);
            app.R.s1mb.tpmMeanTrim = app.R.s1mb.tpmMean(~app.H.s1mb.oeTrimBadLocs,:);
            app.R.s1mb.geneTrim = app.R.s1mb.gene(~app.H.s1mb.oeTrimBadLocs,:);
            
            %% save data
            savedData.dataInfo = app.dataInfo;
            savedData.H = app.H;
            savedData.R = app.R;
            
            save(sprintf('%s%sdata%s%sData.mat',app.dataInfo.path.output,...
                app.dataInfo.delim,app.dataInfo.delim,...
                app.dataInfo.projName),'savedData','-v7.3')
        end

        % Menu selected function: PreloadedDataMenu
        function PreloadedDataMenuSelected(app, event)
            %% select saved data file
            [file,path] = uigetfile('*.mat');
            load(fullfile(path,file))
            
            % overwrite app data structures
            app.dataInfo = savedData.dataInfo;
            app.H = savedData.H;
            app.R = savedData.R;
            
        end

        % Menu selected function: PublicDataMenu
        function PublicDataMenuSelected(app, event)
            publicData = readtable('publicDataIndex.xlsx');
            [indx,~] = listdlg('PromptString','Select Genes to analyze',...
                'ListString',publicData.sample,...
                'ListSize',[500 500]);
            sampleSelect = samples{indx};
        end

        % Menu selected function: chrlevelcomparisonMenu
        function chrlevelcomparisonMenuSelected(app, event)
            D20181213_23x23LpAnalysisV2
        end

        % Menu selected function: AddchromosometofigureMenu
        function AddchromosometofigureMenuSelected(app, event)
            D20181212_addChr2Plot
        end

        % Menu selected function: KarytypegenesetexpressionMenu
        function KarytypegenesetexpressionMenuSelected(app, event)
            D20181119_wntGeneChrDist
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadDataMenu = uimenu(app.UIFigure);
            app.LoadDataMenu.Text = 'Load Data';
            app.IndexfileMenu = uimenu(app.LoadDataMenu);
            app.IndexfileMenu.MenuSelectedFcn = createCallbackFcn(app, @IndexfileMenuSelected, true);
            app.IndexfileMenu.Text = 'Index file';
            app.PreloadedDataMenu = uimenu(app.LoadDataMenu);
            app.PreloadedDataMenu.MenuSelectedFcn = createCallbackFcn(app, @PreloadedDataMenuSelected, true);
            app.PreloadedDataMenu.Text = 'Pre-loaded Data';
            app.PublicDataMenu = uimenu(app.LoadDataMenu);
            app.PublicDataMenu.MenuSelectedFcn = createCallbackFcn(app, @PublicDataMenuSelected, true);
            app.PublicDataMenu.Text = 'PublicData';
            app.AnalyzeMenu = uimenu(app.UIFigure);
            app.AnalyzeMenu.Text = 'Analyze';
            app.ChromatinpartitioningMenu = uimenu(app.AnalyzeMenu);
            app.ChromatinpartitioningMenu.Text = 'Chromatin partitioning';
            app.FiedlervectorMenu = uimenu(app.ChromatinpartitioningMenu);
            app.FiedlervectorMenu.Text = 'Fiedler vector';
            app.Principalcomponent1Menu = uimenu(app.ChromatinpartitioningMenu);
            app.Principalcomponent1Menu.Text = 'Principal component 1';
            app.DifferentialexpressionMenu = uimenu(app.AnalyzeMenu);
            app.DifferentialexpressionMenu.Text = 'Differential expression';
            app.DNfeatureanalysisMenu = uimenu(app.AnalyzeMenu);
            app.DNfeatureanalysisMenu.Text = '4DN feature analysis';
            app.LarntzPerlmanMenu = uimenu(app.AnalyzeMenu);
            app.LarntzPerlmanMenu.Text = 'Larntz-Perlman';
            app.ABswitchingMenu = uimenu(app.AnalyzeMenu);
            app.ABswitchingMenu.Text = 'A/B switching';
            app.EffectiveresistanceMenu = uimenu(app.AnalyzeMenu);
            app.EffectiveresistanceMenu.Text = 'Effective resistance';
            app.VisualizeMenu = uimenu(app.UIFigure);
            app.VisualizeMenu.Text = 'Visualize';
            app.GenomewideMenu = uimenu(app.VisualizeMenu);
            app.GenomewideMenu.Text = 'Genome-wide';
            app.HiCRNAseqMenu = uimenu(app.VisualizeMenu);
            app.HiCRNAseqMenu.Text = 'Hi-C + RNA-seq';
            app.DNfeatureanalysisMenu_2 = uimenu(app.VisualizeMenu);
            app.DNfeatureanalysisMenu_2.Text = '4DN feature analysis';
            app.DataExportMenu = uimenu(app.UIFigure);
            app.DataExportMenu.Text = 'Data Export';
            app.dataInfoMenu = uimenu(app.DataExportMenu);
            app.dataInfoMenu.Text = 'dataInfo';
            app.HMenu = uimenu(app.DataExportMenu);
            app.HMenu.Text = 'H';
            app.kbMenu = uimenu(app.HMenu);
            app.kbMenu.Text = '100kb';
            app.MbMenu = uimenu(app.HMenu);
            app.MbMenu.Text = '1Mb';
            app.RMenu = uimenu(app.DataExportMenu);
            app.RMenu.Text = 'R';
            app.TPMMenu = uimenu(app.RMenu);
            app.TPMMenu.Text = 'TPM';
            app.countsMenu = uimenu(app.RMenu);
            app.countsMenu.Text = 'counts';
            app.RPKMMenu = uimenu(app.RMenu);
            app.RPKMMenu.Text = 'RPKM';
            app.ExamplesMenu = uimenu(app.UIFigure);
            app.ExamplesMenu.Text = 'Examples';
            app.LarntzPerlmanMenu_2 = uimenu(app.ExamplesMenu);
            app.LarntzPerlmanMenu_2.Text = 'Larntz-Perlman';
            app.chrlevelcomparisonMenu = uimenu(app.LarntzPerlmanMenu_2);
            app.chrlevelcomparisonMenu.MenuSelectedFcn = createCallbackFcn(app, @chrlevelcomparisonMenuSelected, true);
            app.chrlevelcomparisonMenu.Text = 'chr-level comparison';
            app.AddchromosometofigureMenu = uimenu(app.ExamplesMenu);
            app.AddchromosometofigureMenu.MenuSelectedFcn = createCallbackFcn(app, @AddchromosometofigureMenuSelected, true);
            app.AddchromosometofigureMenu.Text = 'Add chromosome to figure';
            app.KarytypegenesetexpressionMenu = uimenu(app.ExamplesMenu);
            app.KarytypegenesetexpressionMenu.MenuSelectedFcn = createCallbackFcn(app, @KarytypegenesetexpressionMenuSelected, true);
            app.KarytypegenesetexpressionMenu.Text = 'Karytype gene set expression';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.Text = 'Documentation';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Select "Load Data" from the Menu bar above to begin'};
            app.DNvestigatorLabel = uilabel(app.UIFigure);
            app.DNvestigatorLabel.Text = '4DNvestigator';
        end
    end

    methods (Access = public)

        % Construct app
        function app = fdn

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end